classdef startupApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
    end

    % Dayle Kotturi August 2018
    properties (Access = private)
        
        integrationTimeMS % Description
        darkStem
        rawStem
        dataStem
        spectrometer
        wavenumbers
        numPoints
        true
        false
        severeError
        
    end

    methods (Access = private)
        
        function a = takeSpectrum(app)    
            % get a spectrum
            app.spectrometer.integrationTimeMS = app.integrationTimeMS;
            spectrum = app.spectrometer.getSpectrum(); 
            pixels = app.spectrometer.pixels;
            if (pixels > app.numPoints)
                fprintf('Number of pixels, %d, exceeds expected, %d\n', pixels, ...
                app.numPoints);
            else 
                fprintf('takeSpectrum retrieved %d pixels\n', pixels);
            end
            a = spectrum;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Optionally take a dark at the start of every run. Store it. Subtract it from 
            % each subsequent spectra. What is hard about this is that the sample is in
            % the flowcell, screwed in with 12 screws and a liquid seal. Ideally, the
            % "dark" would be the flowcell, sealed and screwed together without the
            % sample. When the sample is just sitting on quartz, it is easier. Assuming
            % quartz homogeneous, slide can just be repositioned to move sample out of
            % laser line.
            
            % Add possibility of using a dark from file instead of taking it at the
            % start of the run. This feature is needed when a dark cannot be taken
            % because the sample is sealed inside the flowcell. Once per day or per
            % run, the flowcell full of fluid could be sensed to create a new dark.
            
            app.true = 1;
            app.false = 0; 
            app.numPoints = 1024;
            
            %darkData = zeros(1, numPoints, 'double');
            spectrumData = zeros(1, app.numPoints, 'double');
            %lastSpectrumData = zeros(1, numPoints, 'double');
            %rawData = zeros(1, numPoints, 'double');
            %x = zeros(1, numPoints, 'double');
            app.integrationTimeMS = 5000;
            xMin = 0;
            xMax = 1800;
            yMin = 0;
            yMax = 12000;
            % Relative to where Matlab files are. If using the ones in git checkout,
            % \\bme-fs-student2.engr.tamu.edu\graduate\dayle.kotturi\Documents\GitHub\spectrometerTools
            % use:
            app.darkStem = '../../Data/dark-%s.txt';
            app.rawStem = '../../Data/rawSpectrum-%s.txt';
            app.dataStem = '../../Data/spectrum-%s.txt';
            
            % load the DLL
            %32 bit dll = NET.addAssembly('C:\Program Files (x86)\Wasatch Photonics\Wasatch.NET\WasatchNET.dll');
            dll = NET.addAssembly('C:\Program Files\Wasatch Photonics\Wasatch.NET\WasatchNET.dll');
            
            % get a handle to the Driver Singleton
            driver = WasatchNET.Driver.getInstance();
            
            % enumerate any connected spectrometers
            numberOfSpectrometers = driver.openAllSpectrometers();
            fprintf('%d spectrometers found.\n', numberOfSpectrometers);
            if numberOfSpectrometers <= 0
            	app.severeError = tree;
            end
            
            % open the first spectrometer found
            app.spectrometer = driver.getSpectrometer(0);
            
            % access some key properties
            pixels = app.spectrometer.pixels
            modelName = char(app.spectrometer.model);
            serialNum = char(app.spectrometer.serialNumber);
            wavelengths = app.spectrometer.wavelengths;
            laserWavelengthNM = 785.0;
            app.wavenumbers = WasatchNET.Util.wavelengthsToWavenumbers(laserWavelengthNM,... 
                wavelengths);
            
            % this should not be necessary but it helps to make wavenumber into 1x1024
            % array instead of 1x1.
            for i = 1:pixels
                x(i) = app.wavenumbers(i);
            end
            
            % display summary
            fprintf('Found %s %s with %d pixels (%.2f, %.2fnm)\n', ...
                modelName, serialNum, pixels, ...
                wavelengths(1), wavelengths(wavelengths.Length));
            for i = 1:pixels
                fprintf("%g %g \n", app.wavenumbers(i), spectrumData(i)); % use notepad++ to see this is 1 value per line for 1024 lines
            end 
            %size(wavenumbers); % This is misleading b/c it is 1x1
            firstTime = app.true;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % FIX - this is the signal, not the dark
            darkData = takeSpectrum(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = startupApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end